classdef ACTHselect_CNN_coincidence_Github < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ButtonDone   matlab.ui.control.Button
        angleSlider  matlab.ui.control.Slider
        angleLabel   matlab.ui.control.Label
        CheckBox     matlab.ui.control.CheckBox
        UITable      matlab.ui.control.Table
        Button       matlab.ui.control.Button
        ListBox      matlab.ui.control.ListBox
        Label_3      matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
    end


    properties (Access = private)
       
        % 在app里面Browse数据的总文件夹位置
        % .KFB文件需存放在以病理号命名的子文件夹里
        
        cd_path;
        orig_image;
        diag_length;
        movable_image;
        moving = false;
        new_location;
        width;
        height;
        start_relative_location;
        ACTHL = 0;
        ACTHP = 0;
        flag = 0;
        first_line = 1;
        order = 2;
        equal_ACTH;
        coincidence_part;
        
        countP = 0;  countL = 0;
        part1P = 0;  part2P = 0;  part3P = 0;  
        part1L = 0;  part2L = 0;  part3L = 0;
        
        t1=20;
        t2=70;
        t3=120;
        t4=180;
        t5=200;
  
        d;
        % Description
        
    end
    
    properties (Access = public)
        Done = 0; % Description
    end

    methods (Access = private)
    
        function app = recalculate(app)
            if app.CheckBox.Value
%               hdf5_ACTH_file = [app.d '\' value '\ACTH.h5'];
%               info = h5disp(hdf5_ACTH_file, '/image/10/data');
%               img_size = info.Dataspace.Size;
            end
                  
            if app.countP>0           
                per1P = app.part1P/app.countP;
                per2P = app.part2P/app.countP;
                per3P = app.part3P/app.countP;
            else
                per1P = 0;
                per2P = 0;
                per3P = 0;
            end
                
            if app.countL>0
                per1L = app.part1L/app.countL;
                per2L = app.part2L/app.countL;
                per3L = app.part3L/app.countL;
            else
                per1L = 0;
                per2L = 0;
                per3L = 0;
            end
            
            a1 = app.t2/(app.t2+app.t3+app.t4);
            a2 = app.t3/(app.t2+app.t3+app.t4);
            a3 = app.t4/(app.t2+app.t3+app.t4);
        
            app.ACTHP = per3P*double(a1) + per2P*double(a2) + per1P*(1-double(a1)-double(a2)); 
            % per3P*0.15 + per2P*0.25 + perP*0.6
            app.ACTHL = per3L*double(a1) + per2L*double(a2) + per1L*(1-double(a1)-double(a2));
            % disp(ACTHL);
%             app.ACTHPGauge.Value = app.ACTHP;
%             app.ACTHLGauge.Value = app.ACTHL;
           
            app.UITable.Data = [app.ACTHP*100;per3P*100;per2P*100;per1P*100;app.ACTHL*100;per3L*100;per2L*100;per1L*100];
            app.UITable.RowName = {'ACTHP';'P+';'P++';'P+++';'ACTHL';'L+';'L++';'L+++'};
           
            % clc;
            disp('countL,countP');
            disp(app.countL); 
            disp(app.countP);
            disp('nnz: 1L+++ , 2L++ , 3L+')
            disp(app.part1L);
            disp(app.part2L);
            disp(app.part3L);
                        
        end     
        
        function [mus, sigmas, weights] = GMMfit(app, samples, k)
            assert(ismatrix(samples));
            if size(samples,1) > 1
                samples = samples';
            end
            samples = double(samples);
            N = length(samples);
            mus = samples(randperm(N,k));
            Ssquare = var(samples);
            Ssquares = repelem(Ssquare,k);
            weights = ones(1,k)./k;
        
            belong_prob = zeros(k,N);
            break_limit = 0.01;
            for n=1:500
                % 更新所属概率
                for i=1:k
                    mu = mus(i);
                    Ssquare = Ssquares(i);
                    weight = weights(i);
                    belong_prob(i,:) = normpdf(samples, mu, sqrt(Ssquare)).*weight;
                end
                belong_prob = belong_prob ./ sum(belong_prob, 1);   % 对k个分布归一化
                % 更新分布的参数
                can_break = true;
                for i=1:k
                    new_mu = sum(belong_prob(i,:).*samples) / sum(belong_prob(i,:));
                    new_Ssquare = sum(belong_prob(i,:).*(samples-new_mu).^2) / sum(belong_prob(i,:));
                    new_weight = mean(belong_prob(i,:));
                    if (abs(new_mu-mus(i)) > break_limit || abs(new_Ssquare-Ssquares(i)) > break_limit || abs(new_weight-weights(i)) > break_limit)
                        can_break = false;
                    end
                    mus(i) = new_mu;
                    Ssquares(i) = new_Ssquare;
                    weights(i) = new_weight;
                end
                disp('n:');
                disp(n);
                disp('mus:');
                disp(mus);
                disp('Ssquares:');
                disp(Ssquares);
                disp('weights:');
                disp(weights);
                if can_break
                    break;
                end
            end
            sigmas = sqrt(Ssquares);
        end
        
        function [countP,part1P,part2P,part3P,countL,part1L,part2L,part3L] = statistic(app,testP,testL)
            
            testL=testL.*app.coincidence_part;
            testP=testP.*app.coincidence_part;
            countL = nnz(double(testL(testL<213)));
            % 前面输入的testL 为 ACTH_L_dot 去掉白色背景后的像素数
            
            t1_ = app.t1*(app.equal_ACTH/213);
            t2_ = app.t2*(app.equal_ACTH/213);
            t3_ = app.t3*(app.equal_ACTH/213);
            t4_ = app.t4*(app.equal_ACTH/213);
            disp('app.equal_ACTH');
            disp(app.equal_ACTH);
            
            part1L = nnz((double(testL)>double(t1_))&(double(testL)<double(t2_)));
            % part1L +++ 越深染阳性程度越高(20,70)
            part2L = nnz((double(testL)>double(t2_))&(double(testL)<double(t3_)));
            % part2L ++ (70,120)
            part3L = nnz((double(testL)>double(t3_))&(double(testL)<double(t4_)));
            % part3L + (120,180)

            
            countP = nnz(double(testP(testP<213))) - countL;
            
        
            part1P = nnz((double(testP)>double(t1_))&(double(testP)<double(t2_))) - part1L;
            part2P = nnz((double(testP)>double(t2_))&(double(testP)<double(t3_))) - part2L;
            part3P = nnz((double(testP)>double(t3_))&(double(testP)<double(t4_))) - part3L;
                   
        end
        
        function All_prepare(app,i,a) %调用了app.slice_foreground，app.Step_two_item
            name = a;
            
            if ~isfile(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\%d.png'],i))
                app.crop_large_5(i,name,'Tpit');
                app.crop_large_5(i,name,'ACTH'); 
                app.crop_large_10(i,name,'Tpit');
                app.crop_large_10(i,name,'ACTH');
                
                
            end
            % 如果没有生成过0.625的黑白背景图像，则补做
            fig_file = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_0.625\\%d_fore.png'],i);
            if ~isfile(fig_file)
                ACTH_path = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_0.625\\%d.png'],i);
                Tpit_path = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_0.625\\%d.png'],i);
                sep_ACTH = app.slice_foreground(imread(ACTH_path));
                sep_Tpit = app.slice_foreground(imread(Tpit_path));
                imwrite(sep_ACTH,sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_0.625\\%d_fore.png'],i));
                imwrite(sep_Tpit,sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_0.625\\%d_fore.png'],i));
                % imwrite(sep,'foreground.png');
                % imwrite(labeloverlay(im,sep,'Transparency',0.8),'overlay.png');
                clear ACTH_path;clear Tpit_path;clear sep_ACTH;clear sep_Tpit;
                
            end
            
            % 开始找阳性点，顺便给没配准过的图像配准
            ACTH_Manual_L_CNN = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\ACTH_Manual_L_CNN%d.png'],i);
            % 判断是否已找过靠谱的阳性点
            if ~isfile(ACTH_Manual_L_CNN)
                Tpit_path = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\%d.png'],i);
                ACTH_path = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\%d.png'],i);
                Block_ACTH = imread(ACTH_path);
                Block_Tpit = imread(Tpit_path);
                tform_file = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\%d_tform.mat'],i);


                if ~app.CheckBox.Value
                    % 先写自动配准
                    ACTH_L_CNN = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\ACTH_L_CNN%d.png'],i);
                    % 判断是否已找过自动配准的阳性点
                    if ~isfile(ACTH_L_CNN)
                        if isfile(tform_file)
                            load(tform_file);
                        else
                            tform = app.Step_two_item(i,name); 
                            % 以防万一Role_48里有算好的，而Block_ACTH_10里删掉了,再补一份
                            copyfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_tform.png'],i),sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_10\\%d_tform.png'],i));
                            copyfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_tform.mat'],i),sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_10\\%d_tform.mat'],i));
                            
                        end
                        
                        imref = imref2d(size(Block_ACTH));
                        L_Block_Tpit = Block_Tpit;
                        mask_Tpit = ones(size(Block_Tpit(:,:,3)));
                        i1 = 1;
                        hehe=0;
                        mkdir([app.cd_path,'\\CNN\\CNN_Matlab_test\\0']);
                        mkdir([app.cd_path,'\\CNN\\CNN_Matlab_test\\1']);
                        for py = 0:floor((size(Block_Tpit,1)/100)-1)
                            for px = 0:floor((size(Block_Tpit,2)/100)-1)
                                sample_x = (px*100+1); 
                                sample_y = (py*100+1);
                                pane = Block_Tpit(sample_y:(sample_y+99) , sample_x:(sample_x+99),:);
                                if hehe==0
                                    imwrite(pane,sprintf([app.cd_path,'\\CNN\\CNN_Matlab_test\\0\\%d_%04d_%04d_pane.png'],i,sample_x,sample_y));
                                    hehe=1;
                                else
                                    imwrite(pane,sprintf([app.cd_path,'\\CNN\\CNN_Matlab_test\\1\\%d_%04d_%04d_pane.png'],i,sample_x,sample_y));
                                    hehe=0;
                                end
                                i1=i1+1;
                            end
                        end
                        
                        system(['"D:\\Python64\\python.exe" ',app.cd_path,'\\CNN\\CNN_multifunction_Matlab.py test']);
                        % 在Matlab中调用python，给python设置传参
                        test_results = load([app.cd_path,'\\CNN\CNN_Matlab_test\test_results.txt']);
                        
                        rmdir([app.cd_path,'\\CNN\\CNN_Matlab_test\\0'],"s"); % rmdir a.txt s对应函数形式rmdir("a.txt","s");
                        rmdir([app.cd_path,'\\CNN\\CNN_Matlab_test\\1'],"s");
                        i2 =1;
                        for py = 0:floor((size(Block_Tpit,1)/100)-1)
                            for px = 0:floor((size(Block_Tpit,2)/100)-1)
                                sample_x = (px*100+1); 
                                sample_y = (py*100+1);
                                
                                if test_results(i2) == 0  % isempty(locs) || locs(1)>= 20 || locs(1) <= 6
                                    L_Block_Tpit(sample_y:(sample_y+99) , sample_x:(sample_x+99) ,:) = 255;
                                    mask_Tpit(sample_y:(sample_y+99) , sample_x:(sample_x+99)) = 0;
                                    % pane = Block_Tpit(sample_y:(sample_y+99) , sample_x:(sample_x+99),:);
                                    % imwrite(pane,sprintf([app.cd_path,'\\Dot_fft\\',name,'\\_false\\%d_%04d_%04d_pane.png'],i,sample_x,sample_y));
                                else
                                    % pane = Block_Tpit(sample_y:(sample_y+99) , sample_x:(sample_x+99),:);
                                    % imwrite(pane,sprintf([app.cd_path,'\\Dot_fft\\',name,'\\_true\\%d_%04d_%04d_pane.png'],i,sample_x,sample_y));
                                end
                                i2 = i2 + 1;
                            end
                        end
                                                 
                        mask_Tpit = ~mask_Tpit; 
                        mask_3_Tpit = repmat(mask_Tpit,[1 1 3]); % mask为0-1矩阵
                        
                        L_Block_Tpit = Block_Tpit;
                        L_Block_Tpit(mask_3_Tpit) = 255;  
            
                        A = tform.T;
                        A(3,1:2) = A(3,1:2).* (10/5);  % scale=5转变为10
                        tform.T = A;
                        
                        mask_3_ACTH = imwarp(mask_3_Tpit,tform,'OutputView',imref,'FillValue',1); % 把Tpit上的阳性mask映射到ACTH上
                        
                        % 寻找重合部分开始，都保存在Tpit组里
                        effective_ACTH = app.detect_slice_foreground(Block_ACTH,"ACTH");
                        effective_Tpit = app.detect_slice_foreground(Block_Tpit,"Tpit");
                        imwrite(effective_ACTH, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\effective_ACTH_%d.png'],i));
                        imwrite(effective_Tpit, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\effective_Tpit_%d.png'],i));
                        app.coincidence_part = effective_ACTH.*imwarp(effective_Tpit,tform,'OutputView',imref,'FillValue',1); 
                        % 把Tpit上的有效区域映射到ACTH上,与ACTH对比后寻找配准重合部分
                        imwrite(app.coincidence_part, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\coincidence_part_%d.png'],i));
                        % 寻找重合部分结束
                        
                        % 'FillValue',1相当于 Python 里的 FillValue = 1，不写默认填黑色
                        %imshow(mask_ACTH,[]);
                        L_Block_ACTH = Block_ACTH;
                        mask_3_ACTH = logical(mask_3_ACTH);
                        L_Block_ACTH(mask_3_ACTH) = 255; % 把mask_ACTH为true的地方填白
                        mask_ACTH = mask_3_ACTH(:,:,1);
                        
                        imwrite(mask_Tpit, sprintf([app.cd_path,'\\Role_48\\',a,'\\mask_Tpit_%d.png'],i));
                        imwrite(mask_Tpit, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\mask_Tpit_%d.png'],i));
                        imwrite(L_Block_Tpit, sprintf([app.cd_path,'\\Role_48\\',a,'\\Tpit_L_CNN%d.png'],i));
                        imwrite(L_Block_Tpit, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\Tpit_L_CNN%d.png'],i));
                        
                        imwrite(mask_ACTH, sprintf([app.cd_path,'\\Role_48\\',a,'\\mask_ACTH_%d.png'],i));
                        imwrite(mask_ACTH, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\mask_ACTH_%d.png'],i));
                        imwrite(L_Block_ACTH, sprintf([app.cd_path,'\\Role_48\\',a,'\\ACTH_L_CNN%d.png'],i));
                        imwrite(L_Block_ACTH, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\ACTH_L_CNN%d.png'],i));
               
                    end
                    
                    if app.flag
                            hold(app.UIAxes, 'off'); 
                    end
                    app.orig_image = imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\%d_tform.png'],i));
                    im_fixed = imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\%d_tform.png'],i)); % path
                    image(app.UIAxes, im_fixed, 'PickableParts', 'none');
                    hold(app.UIAxes, 'on');
                    app.flag = 1;
                    disableDefaultInteractivity(app.UIAxes); 
                    axis(app.UIAxes, [-70 size(im_fixed,2)+70 -70 size(im_fixed,1)+70]);
                    app.UIAxes.set('DataAspectRatio', [1,1,1]);
                    app.UIAxes.set('DataAspectRatioMode', 'manual');
                    app.movable_image = image(app.UIAxes, app.orig_image, 'PickableParts', 'none', 'AlphaData', 0.7);
                    waitfor(app,'Done',1);
                    hold(app.UIAxes, 'off'); 
                    app.Done = 0;
                    app.flag = 0;
                    
                    
                else  % 手动配准
                    if app.flag
                        hold(app.UIAxes, 'off');
                    end
                    
                    app.orig_image = imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_0.625\\%d.png'],i));
                    im_fixed = imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_0.625\\%d.png'],i)); % path
                    image(app.UIAxes, im_fixed, 'PickableParts', 'none');
                    hold(app.UIAxes, 'on');
                    app.flag = 1;
                    disableDefaultInteractivity(app.UIAxes);
                    axis(app.UIAxes, [-70 size(im_fixed,2)+70 -70 size(im_fixed,1)+70]);
                    app.UIAxes.set('DataAspectRatio', [1,1,1]);
                    app.UIAxes.set('DataAspectRatioMode', 'manual');
                    
                    app.diag_length = ceil(sqrt(size(app.orig_image, 1)^2 + size(app.orig_image, 2)^2));
                    begin_pad = floor([app.diag_length-size(app.orig_image, 1), app.diag_length-size(app.orig_image, 2)]/2);
                    end_pad = [app.diag_length-size(app.orig_image, 1)-begin_pad(1), app.diag_length-size(app.orig_image, 2)-begin_pad(2)];
                    disp_im = padarray(app.orig_image, [begin_pad 0], 0, 'pre');
                    disp_im = padarray(disp_im, [end_pad 0], 0, 'post');
                    
                    app.movable_image = image(app.UIAxes, disp_im, 'PickableParts', 'none', 'AlphaData', 0.8);
                    app.movable_image.XData = [-begin_pad(2) -begin_pad(2)+app.diag_length];
                    app.movable_image.YData = [-begin_pad(1) -begin_pad(1)+app.diag_length];
                    
                    % 暂停运行等待触发
                    waitfor(app,'Done',1);
                    % load('E:\1_task\Role\19_034990\four_view\1_Role_1.mat');
                    tform = affine2d(eye(3));  
                    
                    app.width = floor(size(Block_Tpit,2)/16);
                    app.height = floor(size(Block_Tpit,1)/16);
                    
                    A = [1,0,0;0,1,0;0,0,1];  
                    A(3,1:2) = [-app.width/2,-app.height/2];

                    B = [1,0,0;0,1,0;0,0,1];
                    r = app.angleSlider.Value;
                    B(1:2,1:2) = [cosd(r),-sind(r);sind(r),cosd(r)];
                    
                    C = [1,0,0;0,1,0;0,0,1];
                    C(3,1:2) = [app.width/2,app.height/2];
                    
                    D = [1,0,0;0,1,0;0,0,1];
                    D(3,1:2) = app.new_location(1:2) + [begin_pad(2),begin_pad(1)];
                    
                    
                    E = A*B*C*D; 
                    
                    E(3,1:2) = E(3,1:2).* (5/0.625);
                    
                    tform.T = E;
                    save(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\%d_Manual.mat'],i) , 'tform');
%                     
%                     
                    
                    
                    hold(app.UIAxes, 'off'); 
                    app.Done = 0;
                    app.flag = 1;
                    
                    
                    imref = imref2d(size(Block_ACTH)); 
                    L_Block_Tpit = Block_Tpit;
                    mask_Tpit = ones(size(Block_Tpit(:,:,3)));
                    i1 = 1;
                    test = [];
                    for py = 0:floor((size(Block_Tpit,1)/100)-1)
                        for px = 0:floor((size(Block_Tpit,2)/100)-1)
                            sample_x = (px*100+1); 
                            sample_y = (py*100+1);
                            pane = Block_Tpit(sample_y:(sample_y+99) , sample_x:(sample_x+99),:);
                            b_pane = im2double(pane(:,:,3));
                            window = fspecial('gaussian',100,35);
                            window = window./max(window,[],'all');
                            c = b_pane.*window;
                            y1 = fft2(c);
            
                            y2=fftshift(y1);
                            y3=log1p(abs(y2));
                            
                            avgs = r_distribution(y3);               
                            test(i1,:)=avgs;
                            i1=i1+1;
                            
                        end
                    end
                    test_samples = test(:,2:end);
                    load('.\detect_dot\Holdout_Quadratic_CNN.mat');
                    test_results = Holdout_Quadratic_CNN.predictFcn(test_samples);
                    i2 =1;
                    for py = 0:floor((size(Block_Tpit,1)/100)-1)
                        for px = 0:floor((size(Block_Tpit,2)/100)-1)
                            sample_x = (px*100+1); 
                            sample_y = (py*100+1);
                            
                            if test_results(i2) == 0  % isempty(locs) || locs(1)>= 20 || locs(1) <= 6
                                L_Block_Tpit(sample_y:(sample_y+99) , sample_x:(sample_x+99) ,:) = 255;
                                mask_Tpit(sample_y:(sample_y+99) , sample_x:(sample_x+99)) = 0;
                                % pane = Block_Tpit(sample_y:(sample_y+99) , sample_x:(sample_x+99),:);
                                % imwrite(pane,sprintf([app.cd_path,'\\Dot_fft\\',name,'\\_false\\%d_%04d_%04d_pane.png'],i,sample_x,sample_y));
                            else
                                % pane = Block_Tpit(sample_y:(sample_y+99) , sample_x:(sample_x+99),:);
                                % imwrite(pane,sprintf([app.cd_path,'\\Dot_fft\\',name,'\\_true\\%d_%04d_%04d_pane.png'],i,sample_x,sample_y));
                            end
                            i2 = i2 + 1;
                        end
                    end
                                             
                    mask_Tpit = ~mask_Tpit; 
                    mask_3_Tpit = repmat(mask_Tpit,[1 1 3]); 
                    
                    L_Block_Tpit = Block_Tpit;
                    L_Block_Tpit(mask_3_Tpit) = 255;  
        
                    A = tform.T;
                    A(3,1:2) = A(3,1:2).* (10/5);  % scale=5转变为10
                    tform.T = A;
                    
                    mask_3_ACTH = imwarp(mask_3_Tpit,tform,'OutputView',imref,'FillValue',1); % 把Tpit上的阳性mask映射到ACTH上
                    
                    % 寻找重合部分开始，都保存在Tpit组里
                    effective_ACTH = app.detect_slice_foreground(Block_ACTH,"ACTH");
                    effective_Tpit = app.detect_slice_foreground(Block_Tpit,"Tpit");
                    imwrite(effective_ACTH, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\effective_ACTH_%d.png'],i));
                    imwrite(effective_Tpit, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\effective_Tpit_%d.png'],i));
                    app.coincidence_part = effective_ACTH.*imwarp(effective_Tpit,tform,'OutputView',imref,'FillValue',1); 
                    % 把Tpit上的有效区域映射到ACTH上,与ACTH对比后寻找配准重合部分
                    imwrite(app.coincidence_part, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\coincidence_part_%d.png'],i));
                    % 寻找重合部分结束
                    
                    
                    %imshow(mask_ACTH,[]);
                    L_Block_ACTH = Block_ACTH;
                    mask_3_ACTH = logical(mask_3_ACTH);
                    L_Block_ACTH(mask_3_ACTH) = 255; 
                    mask_ACTH = mask_3_ACTH(:,:,1);
                    
                    imwrite(mask_Tpit, sprintf([app.cd_path,'\\Role_48\\',a,'\\mask_Tpit_%d.png'],i));
                    imwrite(mask_Tpit, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\mask_Tpit_%d.png'],i));
                    imwrite(L_Block_Tpit, sprintf([app.cd_path,'\\Role_48\\',a,'\\Tpit_Manual_L_CNN%d.png'],i));
                    imwrite(L_Block_Tpit, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\Tpit_Manual_L_CNN%d.png'],i));
                    
                    imwrite(mask_ACTH, sprintf([app.cd_path,'\\Role_48\\',a,'\\mask_ACTH_%d.png'],i));
                    imwrite(mask_ACTH, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\mask_ACTH_%d.png'],i));
                    imwrite(L_Block_ACTH, sprintf([app.cd_path,'\\Role_48\\',a,'\\ACTH_Manual_L_CNN%d.png'],i));
                    imwrite(L_Block_ACTH, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\ACTH_Manual_L_CNN%d.png'],i));
                end
            end
        end
        
        function crop_large_10(app,i,a,str)
           
            mkdir([app.cd_path,'\\sample_block\\',a,'\\Block_pad_',str,'_10']);
            mkdir([app.cd_path,'\\sample_block\\',a,'\\Block_',str,'_10']);
            mkdir(sprintf([app.cd_path,'\\sample_block\\',a,'\\Select_',str,'_%d'],i));
            bbox_file = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_',str,'_0.625\\%d_bbox.txt'],i);
            bbox = load(bbox_file);
           
            xy = bbox(1:2);
            wh = bbox(3:4);
            
            new_xy = (xy-0.5).*16+0.5;
            new_wh = wh*16;
            new_bbox = [new_xy new_wh];


            x1 = new_xy(1);
            y1 = new_xy(2);
            x2 = x1 + new_wh(1);
            y2 = y1 + new_wh(2);
        
            % 把被选中的碎片集中存放到Select_Tpit_1中准备拼凑
            piece = dir([app.cd_path,'\\sample_block\\',a,'\\',str,'_10\\????_????.jpg']);
            for p = 1:size(piece,1)
                number = piece(p).name;
                y = str2double(number(1:4))*256; 
                x = str2double(number(6:9))*256;
        
                if (x+255)>=x1 && x<=x2 && (y+255)>=y1 && y<=y2
                   outputdir = sprintf([app.cd_path,'\\sample_block\\',a,'\\Select_',str,'_%d'],i);
                   
                   copyfile([app.cd_path,'\\sample_block\\',a,'\\',str,'_10\\',number],outputdir); 
        
                end           
            end
        
            % 开始在Select_Tpit_1拼凑,结果存在Gather_Tpit_1中
            selected_piece = dir(sprintf([app.cd_path,'\\sample_block\\',a,'\\Select_',str,'_%d'],i));
        
            
            first_number = selected_piece(3).name;        
            start_x = str2double(first_number(6:9))*256;
            start_y = str2double(first_number(1:4))*256;
            
            % outputblock = zeros((size(selected_piece)-3)*256,(size(selected_piece)-3)*256);实际长宽不一样
            outputblock = zeros(256,256,3,'uint8');
            
            new_xy = (xy-0.5).*16+0.5 - [start_x-1,start_y-1];
            new_wh = wh*16;
            new_bbox = [new_xy new_wh];
            % 把带外圈的block存入Block_pad_Tpit_10，后续再切边
            for s = 3:size(selected_piece,1)
               
               selected_number = selected_piece(s).name;
               filly = str2double(selected_number(1:4))*256 - start_y;
               fillx = str2double(selected_number(6:9))*256 - start_x;
               
               im = imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Select_',str,'_%d\\',selected_number],i));
               if size(im) == [256,256,3] 
                   outputblock(filly+1:filly+256 ,fillx+1:fillx+256,:) = im; 
               else
                   [ky,kx,~] = size(im); 
                   outputblock(filly+1:filly+ky ,fillx+1:fillx+kx,:) = im;
               end
        
            end    
            imwrite(outputblock, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_pad_',str,'_10\\%d.png'],i));
            % 切掉多余的边缘，正式存入Block_Tpit_10
            large_file = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_pad_',str,'_10\\%d.png'],i);
            large_image = imread(large_file);
            large_image = imcrop(large_image, new_bbox);
            imwrite(large_image,sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_',str,'_10\\%d.png'],i));
            
        end
        
        function [n_cluster, cluster_bboxes] = image2cluster_bboxes(app,im) % 调用了 cluster_by_shortest_distance  segment_slice_foreground
            regions = app.segment_slice_foreground(im);
            areas = app.region_areas(regions);
            cluster_idx = app.cluster_by_shortest_distance(regions); 
            n_cluster = max(cluster_idx);
            cluster_areas = zeros(1,n_cluster);
            for i=1:n_cluster
                cluster_areas(i) = sum(areas(cluster_idx == i));
            end
            [~,cluster_order] = sort(cluster_areas,'descend');
            region_bboxes = regionprops(regions,'BoundingBox');
            cluster_bboxes = cell(1,n_cluster);
            region_bboxes = struct2cell(region_bboxes); 
            for i=1:n_cluster
                cluster_bboxes{i} = app.union_bounding_box(region_bboxes(cluster_idx == i));
            end
            cluster_bboxes = cluster_bboxes(cluster_order);
        end
        
        function ret = region_areas(app,cc)
            areas=regionprops(cc,'Area');   % areas = 4x1 struct
            
            areas_arr=zeros(1,cc.NumObjects);
            for i=1:cc.NumObjects
                areas_arr(i)=areas(i).Area;
            end
            ret = areas_arr;  % areas_arr = [44578,640,173869,1833]
        end
        
        function ret = union_bounding_box(app,rectangles)
            rectangle_matrix = cell2mat(rectangles');
            top_left = rectangle_matrix(:,1:2);
            bottom_right = top_left+rectangle_matrix(:,3:4);
            final_top_left = min(top_left,[],1);
            final_bottom_right = max(bottom_right,[],1);
            width_height = final_bottom_right-final_top_left;
            ret = [final_top_left width_height];
        end
        
        function crop_large_5(app,i,a,str)
            bbox_file = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_',str,'_0.625\\%d_bbox.txt'],i);
            bbox = load(bbox_file);
            xy = bbox(1:2);
            wh = bbox(3:4);
            new_xy = (xy-0.5).*8+0.5; % 0.625到5要乘8
            new_wh = wh*8;
            new_bbox = [new_xy new_wh];
            large_file = [app.cd_path,'\\sample_block\\',a,'\\',str,'_5.jpg'];
            large_image = imread(large_file);
            %large_image = rgb2gray(large_image);
            large_image = imcrop(large_image, new_bbox);
            mkdir([app.cd_path,'\\sample_block\\',a,'\\Block_',str,'_5']);
        
            if isfile(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_',str,'_5\\%d.png'],i))
            else    
                imwrite(large_image,sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_',str,'_5\\%d.png'],i));
            end
        end
        
        function ret = slice_foreground(app,im)
            if ndims(im) == 2
                gray=im;
            else
                gray=rgb2gray(im);
            end
            % 方差：
            std = stdfilt(gray,strel('disk',4).Neighborhood);
        
            % 分割
            sep = imbinarize(rescale(std));
            % imshow(labeloverlay(im,sep,'Transparency',0.8))
            % 去掉边缘
            sep(1:15,:)=false;
            sep(end-15:end,:)=false;
            sep(:,1:15)=false;
            sep(:,end-15:end)=false;
            % 闭操作
            sep = imclose(sep,strel('disk',3));
            ret = sep;
            
        end
        
        function [tform_specific,error_specific] = Step_two_48(app,i,name,item_surface)
            if item_surface<=20
                item = item_surface; surface = 0;
            else
                item = item_surface-20; surface = 1;
            end
            mkdir(sprintf([app.cd_path,'\\Role_48\\',name,'\\four_view_%d'],item_surface));
        %   Role_fig_file = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_5\\%d_Role.fig'],i);
        %   Best_fig_file = sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_5\\%d_Best.fig'],i);
            Role_fig_file = sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_Role_mono_%d.mat'],i,item_surface);
            Best_fig_file = sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_%d.mat'],i,item_surface);
            % 读出0.625黑白图像和原图，分别给配准和显示备用
            ACTH_fore = imread(sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_0.625\\%d_fore.png'],i));
            Tpit_fore = imread(sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_Tpit_0.625\\%d_fore.png'],i));
            ACTH = imread(sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_5\\%d.png'],i));
            Tpit = imread(sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_Tpit_5\\%d.png'],i));
        
            % 判断是否已生成Role.mat,无则补算
            if ~isfile(Role_fig_file)
        
                [optimizer, metric] = imregconfig('monomodal');          
        
                error = zeros(1,item);
               
        
                % gray1 = im2uint8(ACTH_fore);% im2uint8得到0-255，uint8得到0-1
                gray1 = ACTH_fore;
                % 9*9区域的熵
                entropy1=entropyfilt(gray1,true(5,5));
                entropy1=rescale(entropy1);
                %将数组的条目缩放到区间 [0,1]  
                
                for r =1:item
        
                    % gray2 = im2uint8(Tpit_fore);
                    gray2 = Tpit_fore;
                    tform = affine2d(eye(3));
                    A = [1,0,0;0,1,0;0,0,1];  
                    A(3,1:2) = [-size(gray2,2)/2,-size(gray2,1)/2];
                    B = [1,0,0;0,1,0;0,0,1];
                    B(1:2,1:2) = [cosd(r*(360/item)),-sind(r*(360/item));sind(r*(360/item)),cosd(r*(360/item))];
                    C = [1,0,0;0,1,0;0,0,1];
                    C(3,1:2) = [size(gray2,2)/2,size(gray2,1)/2];
                    tform.T = A * B * C;
                    imref = imref2d(size(gray1)); 
                    gray2 = imwarp(gray2,tform,'OutputView',imref);
                    entropy2=entropyfilt(gray2,true(5,5));
                    entropy2=rescale(entropy2);
        
                    tform = imregtform(entropy2,entropy1,'rigid',optimizer,metric);
                   
                    imref = imref2d(size(entropy1));
                    moved2 = imwarp(gray2,tform,'OutputView',imref);
        
                    save(sprintf([app.cd_path,'\\Role_48\\',name,'\\four_view_%d\\%d_Role_%d.mat'],item_surface,i,r) , 'tform');
        %             error(r) = sum(power(single(moved2)-single(gray1),2),'all');slice_foreground;
                    error(r) = sum(abs(single(registration_slice_foreground(app,moved2,surface))-single(registration_slice_foreground(app,gray1,surface))),'all');
                    
        
                end
                
                
                [~,sort_r] = sort(error(1:item)); 
                r = sort_r(1);
        
                load(sprintf([app.cd_path,'\\Role_48\\',name,'\\four_view_%d\\%d_Role_%d.mat'],item_surface,i,r));
        
                T = tform.T;        
        
                A = [1,0,0;0,1,0;0,0,1];  
                A(3,1:2) = [-size(gray2,2)/2,-size(gray2,1)/2];
        
                B = [1,0,0;0,1,0;0,0,1];
                B(1:2,1:2) = [cosd(r*(360/item)),-sind(r*(360/item));sind(r*(360/item)),cosd(r*(360/item))];
        
                C = [1,0,0;0,1,0;0,0,1];
                C(3,1:2) = [size(gray2,2)/2,size(gray2,1)/2];
                E = A*B*C;        
                E(3,1:2) = E(3,1:2).* (5/0.625);
                tform.T = E * T;
        
                imref = imref2d(size(ACTH)); % 设置输出范围
        
                moved2_best = imwarp(Tpit,tform,'OutputView',imref);
                clf;
                imshowpair(ACTH,moved2_best)
                saveas(gcf(),sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_Role_mono_%d.png'],i,item_surface));
                % saveas(gcf(),sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_5\\%d_Role_mono.png'],i));
        
                save(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_Role_mono_%d.mat'],i,item_surface) , 'tform');
                % save(sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_5\\%d_Role_mono.mat'],i) , 'tform');        
            end
        
            % 判断是否已生成Best.mat,无则补算
            if ~isfile(Best_fig_file)
                [optimizer, metric] = imregconfig('multimodal');
                load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_Role_mono_%d.mat'],i,item_surface) , 'tform');
                
                tform0 = tform;
                gray1 = rgb2gray(ACTH);
                % 如果是fore黑白二值化图像，则不需要转换
        
                % entropy1=entropyfilt(gray1,true(9,9));  % 9*9区域的熵
                entropy1=rescale(gray1);
                
        
                gray2 = rgb2gray(Tpit);
                % entropy2=entropyfilt(gray2,true(9,9));
                entropy2=rescale(gray2);
        
                format long; 
                disp(tform0.T);        
                disp(tform0.T(1:2,1:2));
                
                det_error = abs(det(tform0.T)-1);
                disp(det_error);
                addpath([app.cd_path,'//affine2d_norigid']);
                tform0 = affine2d_norigid(tform0.T); 
                tform = imregtform(entropy2,entropy1,'rigid',optimizer,metric,'InitialTransformation',tform0);
                % 函数输出变换矩阵
                % 导出从Tpit到ACTH的变换矩阵class
                % 要求这个变换首先要相似，其次面积比(行列式)为1 
                
                tform_specific = tform;
                imref = imref2d(size(entropy1));
                moved2 = imwarp(gray2,tform,'OutputView',imref);
                clf;
                imshowpair(ACTH,moved2)
                % 保存刚刚显示过的fig文件，和相应的tform.mat
                saveas(gcf(),sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_%d.png'],i,item_surface));
                % saveas(gcf(),sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_5\\%d_Best_mono.png'],i));
                save(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_%d.mat'],i,item_surface) , 'tform');
                % save(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_5\\%d_Best_mono.mat'],i) , 'tform');
        %         error_specific = sum(power(single(moved2)-single(ACTH),2),'all');
                
                subplot(121);
                fore_moved2 = registration_slice_foreground(app,moved2,surface);
                imshow(single(fore_moved2),[]);
                subplot(122);
                fore_ACTH = registration_slice_foreground(app,ACTH,surface);
                imshow(single(fore_ACTH),[]);
                saveas(gcf(),sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_size_%d.png'],i,item_surface));
                clf;
        
                error_specific = sum(abs(single(fore_moved2)-single(fore_ACTH)),'all');
                    % sum对二维数组要加'all'
                writematrix(error_specific,sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_%d.txt'],i,item_surface));
            end 
        end
        
        function ret = cluster_by_shortest_distance(app,cc)
            if cc.NumObjects > 1
                label_matrix = labelmatrix(cc); 
                fg = label_matrix > 0;    
                [D,idx] = bwdist(fg);     
                %cc = bwconncomp(fg);
                nearest_region_label = label_matrix(idx);   
        
                % 找最近区域场交界
                dilated_regions = imdilate(nearest_region_label, ones(3,3));    
                boundary = nearest_region_label ~= dilated_regions; 
        
                % 等于boundary_index = find(boundary);
                boundary_index = label2idx(int32(boundary));   
                boundary_index = boundary_index{1};     
        
                label1 = nearest_region_label(boundary_index);  % 膨胀前（较小的区域标号）
                label2 = dilated_regions(boundary_index);   % 膨胀后（较大的区域标号）
                
                
                distance_matrix = Inf(cc.NumObjects,cc.NumObjects);
                for i=1:size(boundary_index,1)
                    distance_matrix(label1(i),label2(i)) = min(distance_matrix(label1(i),label2(i)),D(boundary_index(i)));
                end
                distance_matrix = min(distance_matrix, distance_matrix');   % 变为对称矩阵
                distance_matrix(eye(cc.NumObjects,'logical')) = 0;  % 对角线填0
        
                % 聚类
                tree = linkage(squareform(distance_matrix),'single');
            %     dendrogram(tree);
                clusters = cluster(tree,'cutoff',100,'criterion','distance');
                % 0.625用100切，2.5用400切
                ret = clusters;
            else 
                ret = 1;
            end
        
            
        end
        
        function ret = segment_slice_foreground(app,im)   % 用于聚类前的预处理
            if ndims(im) == 2
                gray=im;
            else
                gray=rgb2gray(im);
            end
        
            % 方差：
            std=stdfilt(gray,strel('disk',4).Neighborhood);
        
            % 分割
            sep=imbinarize(rescale(std));
            % imshow(labeloverlay(im,sep,'Transparency',0.8))
            % 去掉边缘
            sep(1:15,:)=false;
            sep(end-15:end,:)=false;
            sep(:,1:15)=false;
            sep(:,end-15:end)=false;
        
            % 闭操作
            sep = imclose(sep,strel('disk',3));
            
            
            cc=bwconncomp(sep);
            areas=regionprops(cc,'Area');
        
            
            areas_arr=zeros(1,cc.NumObjects);
            for i=1:cc.NumObjects
                areas_arr(i)=areas(i).Area;
            end


            cc.PixelIdxList = cc.PixelIdxList(areas_arr >= 400);
            
            cc.NumObjects = size(cc.PixelIdxList, 2);
        
            ret = cc;
            
        end
        
        function All_segment_cluster_registration(app,a) %调用了app.image2cluster_bboxes
            if ~isfile([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_0.625\\1.png'])
                % 对整张0.625进行聚类分块，ACTH和Tpit当中分出较少的记为Block
                ACTH = imread([app.cd_path,'\\sample_block\\',a,'\\ACTH_0.625.jpg']);
                % ACTH = rgb2gray(ACTH);
                Tpit = imread([app.cd_path,'\\sample_block\\',a,'\\Tpit_0.625.jpg']);
                % Tpit = rgb2gray(Tpit);
                [ACTH_n_cluster, ACTH_cluster_bboxes] = app.image2cluster_bboxes(ACTH);
                [Tpit_n_cluster, Tpit_cluster_bboxes] = app.image2cluster_bboxes(Tpit);
                
                % 保存平衡值
                
                
                mkdir([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_0.625']);
                mkdir([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_0.625']);
                for i=1:min(ACTH_n_cluster,Tpit_n_cluster)
                    ACTH_bbox = ACTH_cluster_bboxes{i};
                    Tpit_bbox = Tpit_cluster_bboxes{i};
                    ACTH_block = imcrop(ACTH, ACTH_bbox);
                    Tpit_block = imcrop(Tpit, Tpit_bbox);
                    imwrite(ACTH_block,sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_0.625\\%d.png'],i));
                    writematrix(ACTH_bbox, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_0.625\\%d_bbox.txt'],i));
                    imwrite(Tpit_block,sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_0.625\\%d.png'],i));
                    writematrix(Tpit_bbox, sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_0.625\\%d_bbox.txt'],i));
                end
                clear ACTH_bbox;clear ACTH_block;clear Tpit_bbox;clear Tpit_block;clear ACTH;clear Tpit;
                clear [ACTH_n_cluster, ACTH_cluster_bboxes];clear [Tpit_n_cluster, Tpit_cluster_bboxes];
            end
            
        end
        
        function ret = Step_two_item(app,i,name)

            tform_file = sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_tform.mat'],i);
            if isfile(tform_file)
                load(tform_file);
            else
                error = inf(1,43); 
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_1.mat'],i))
                    [tform(1),error(1)] = entropy_registration(app,i,name,1);
                else          
                    tform(1) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_1.mat'],i)).tform;
                    error(1) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_1.txt'],i));
                end
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_2.mat'],i))
                    [tform(2),error(2)] = entropy_registration(app,i,name,2);
                else          
                    tform(2) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_2.mat'],i)).tform;
                    error(2) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_2.txt'],i));
                end
                % 不补洞的配准
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_21.mat'],i))
                    [tform(21),error(21)] = entropy_registration(app,i,name,21);
                else          
                    tform(21) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_21.mat'],i)).tform;
                    error(21) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_21.txt'],i));
                end
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_22.mat'],i))
                    [tform(22),error(22)] = entropy_registration(app,i,name,22);
                else          
                    tform(22) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_22.mat'],i)).tform;
                    error(22) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_22.txt'],i));
                end
                
                % 补洞后的配准
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_4.mat'],i))
                    [tform(4),error(4)] = Step_two_48(app,i,name,4); % 导出从Tpit到ACTH的变换矩阵class
                else          
                    tform(4) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_4.mat'],i)).tform;
                    error(4) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_4.txt'],i));
                end
                
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_8.mat'],i))
                    [tform(8),error(8)] = Step_two_48(app,i,name,8); % 导出从Tpit到ACTH的变换矩阵class
                else          
                    tform(8) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_8.mat'],i)).tform;
                    error(8) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_8.txt'],i));
                end
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_12.mat'],i))
                    [tform(12),error(12)] = Step_two_48(app,i,name,12); % 导出从Tpit到ACTH的变换矩阵class
                else          
                    tform(12) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_12.mat'],i)).tform;
                    error(12) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_12.txt'],i));
                end
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_20.mat'],i))
                    [tform(20),error(20)] = Step_two_48(app,i,name,20); % 导出从Tpit到ACTH的变换矩阵class
                else          
                    tform(20) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_20.mat'],i)).tform;
                    error(20) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_20.txt'],i));
                end
                % 不补洞下的配准
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_24.mat'],i))
                    [tform(24),error(24)] = Step_two_48(app,i,name,24); % 导出从Tpit到ACTH的变换矩阵class
                else          
                    tform(24) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_24.mat'],i)).tform;
                    error(24) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_24.txt'],i));
                end
                % entropyfilt的配准
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_31.mat'],i))
                    [tform(31),error(31)] = entropy_registration(app,i,name,31);
                else          
                    tform(31) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_31.mat'],i)).tform;
                    error(31) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_31.txt'],i));
                end
                if ~isfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_32.mat'],i))
                    [tform(32),error(32)] = entropy_registration(app,i,name,32);
                else          
                    tform(32) = load(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_32.mat'],i)).tform;
                    error(32) = load(sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_32.txt'],i));
                end
                
                [~,sort_item] = sort(error(1:43));
                item = sort_item(1); 
                tform = tform(item);      
                save(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_tform.mat'],i) , 'tform');
                save(sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_10\\%d_tform.mat'],i) , 'tform');
                % app.Step_two_item包含了分别保存tform.mat和tform.png在sample_block和Role_48两处
                items = 1:1:43;
                for other_item = items(1:end)
                    if other_item ~= item
                        delete(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_Role_mono_%d.mat'],i,other_item));
                        delete(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_Role_mono_%d.png'],i,other_item));
                        delete(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_%d.mat'],i,other_item));
                        delete(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_%d.png'],i,other_item));
                        
                    else
                        copyfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_%d.png'],i,other_item),sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_tform.png'],i));
                        copyfile(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_%d.png'],i,other_item),sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_10\\%d_tform.png'],i));
                        % app.Step_two_item包含了分别保存tform.mat和tform.png在sample_block和Role_48两处
                    end
                end
            end
            ret = tform;
        end
        
        function [tform_specific,error_specific] = entropy_registration(app,i,name,item_surface)
            surface = 0;
            entropy = 0;
            if item_surface<=20
                item = item_surface; 
                surface = 0; 
                entropy = 0;
            elseif item_surface>20 && item_surface<=30
                item = item_surface-20; 
                surface = 1;
                entropy = 0;
            elseif item_surface>=30
                item = item_surface-30;
                surface = 0;
                entropy = 1;
            end
            
            if item == 1        
                style = 'monomodal';
            elseif item == 2           
                style = 'multimodal';
            end
            ACTH = imread(sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_5\\%d.png'],i));
            
            % 'G:\\1_task\\sample_block\\20_006371\\Block_ACTH\\1.png'
            gray1=rgb2gray(ACTH);
            % 9*9区域的熵
            if entropy == 1
                entropy1 = entropyfilt(gray1,true(5,5));
                entropy1 = rescale(entropy1); 
            end
            
            Tpit = imread(sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_Tpit_5\\%d.png'],i));
            
            
            gray2 = rgb2gray(Tpit);
            if entropy == 1
                entropy2 = entropyfilt(gray2,true(5,5));
                entropy2 = rescale(entropy2);
            end
            % 在窗口里选择“多模态配准”，变换选rigid
            % registrationEstimator(entropy2,entropy1)
        
            % 生成默认参数
            [optimizer, metric] = imregconfig(style);
            
            
            % 配准
            if entropy == 0
                tform = imregtform(gray2,gray1,'rigid',optimizer,metric);
            elseif entropy == 1
                tform = imregtform(entropy2,entropy1,'rigid',optimizer,metric);
            end
            
            imref = imref2d(size(ACTH));
            % imref=affineOutputView(size(entropy2),tform,'BoundsStyle','SameAsInput');
            
            moved2 = imwarp(gray2,tform,'OutputView',imref);
                
            tform_specific = tform;
            clf;
            imshowpair(ACTH,moved2)
            
            saveas(gcf(),sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_%d.png'],i,item_surface));
            % saveas(gcf(),sprintf([app.cd_path,'\\sample_block\\',name,'\\Block_ACTH_5\\%d_Best_mono.png'],i));
            save(sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_mono_multi_%d.mat'],i,item_surface) , 'tform');
            
            subplot(121);
            fore_moved2 = registration_slice_foreground(app,moved2,surface);
            imshow(single(fore_moved2),[]);
            subplot(122);
            fore_ACTH = registration_slice_foreground(app,ACTH,surface);
            imshow(single(fore_ACTH),[]);
            saveas(gcf(),sprintf([app.cd_path,'\\Role_48\\',name,'\\%d_size_%d.png'],i,item_surface));
            clf;
                
        %     error_specific = sum(power(single(fore_moved2)-single(fore_ACTH),2),'all');
            error_specific = sum(abs(single(fore_moved2)-single(fore_ACTH)),'all');
            writematrix(error_specific,sprintf( [app.cd_path,'\\Role_48\\',name,'\\%d_error_%d.txt'],i,item_surface));
        end
        
        function ret = registration_slice_foreground(app,im,surface)
            if ndims(im) == 2
                gray=im;
            else
                gray=rgb2gray(im);
            end
            % 方差：
            std = stdfilt(gray,strel('disk',4).Neighborhood);
        
            % 分割
            sep = imbinarize(rescale(std));
            
            % 去掉边缘
            sep(1:15,:)=false;
            sep(end-15:end,:)=false;
            sep(:,1:15)=false;
            sep(:,end-15:end)=false;
            % 闭操作
            sep = imclose(sep,strel('disk',3));
            sep = imdilate(sep, ones(3,3));    % 膨胀
            if surface == 0
                sep=imfill(sep,'holes');
            end
            ret = sep; 
        end
        
        function ret = equal_background(app,im)
            
            if ndims(im) == 2
                gray=im;
            else
                gray=rgb2gray(im);
            end
        
            % 方差：
            std=stdfilt(gray,strel('disk',4).Neighborhood);
        
            % 分割
            sep=imbinarize(rescale(std));
            
            sep(1:15,:)=false;
            sep(end-15:end,:)=false;
            sep(:,1:15)=false;
            sep(:,end-15:end)=false;
        
            % 闭操作
            sep = imclose(sep,strel('disk',3));
            
            % 计算背景值
            ret = mean(gray(~sep), 'all');
        end
        
        function ret = detect_slice_foreground(app,im,dye)
            % 照抄registration_slice_foreground
            if dye == "ACTH"
                dye_threshold = app.equal_ACTH;
            elseif dye == "Tpit"
                dye_threshold = app.equal_ACTH;
            end
            
            if ndims(im) == 2
                gray=im;
            else
                gray=rgb2gray(im);
            end
            % 方差：
            std = stdfilt(gray,strel('disk',5).Neighborhood);
        
            % 分割
            sep = imbinarize(rescale(std));
           
            
            % 闭操作
            sep = imclose(sep,strel('disk',5));
            sep = imdilate(sep, ones(3,3));    % 膨胀
            
            sep=imfill(sep,'holes'); 
            
            threshold = im(:,:,3);
            dyeing = threshold<(dye_threshold+15);
            threshold = im2double(threshold).*dyeing; 
                    
            sep = threshold.*sep;
            
            ret = sep;
             
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             cd C:\Users\Administrator\Desktop
%             pyenv('Version','E:\Program Files\Python37\python.exe');
%             insert(py.sys.path,int(0),'G:\A超长寒假程序');
            % 都要作为回调函数创建
        end

        % Callback function
        function t1SliderValueChanged(app, event)
            app.t1 = app.t1Slider.Value;
            recalculate(app);
        end

        % Callback function
        function t2SliderValueChanged(app, event)
            app.t2 = app.t2Slider.Value;
            recalculate(app);
        end

        % Callback function
        function t3SliderValueChanged(app, event)
            app.t3 = app.t3Slider.Value;
            recalculate(app);
        end

        % Callback function
        function t4SliderValueChanged(app, event)
            app.t4 = app.t4Slider.Value;
            recalculate(app);
        end

        % Callback function
        function a1SliderValueChanged(app, event)
            app.a1 = app.a1Slider.Value;
            recalculate(app);
        end

        % Callback function
        function a2SliderValueChanged(app, event)
            app.a2 = app.a2Slider.Value;
            recalculate(app);
        end

        % Callback function
        function a3SliderValueChanged(app, event)
            app.a3 = app.a3Slider.Value;
            recalculate(app);
        end

        % Callback function
        function t5SliderValueChanged(app, event)
            app.t5 = app.t5Slider.Value;
            recalculate(app);
        end

        % Callback function
        function bSliderValueChanged(app, event)
            app.b = app.bSlider.Value;
            recalculate(app);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.UITable.Data = zeros(8,1);
            app.UITable.RowName = {'ACTHP';'P+';'P++';'P+++';'ACTHL';'L+';'L++';'L+++'};
%             im=py.read_image_example.read_kfb_example("E:\kfb\2017-11174.kfb",int64(1),int64(1),int64(512),int64(512),int64(5),int64(6));
%             if app.CheckBox.Value
            cd([app.d '\' value]);
            app.countP = 0;     app.countL = 0;
            app.part1P = 0;     app.part2P = 0;
            app.part3P = 0;     app.part1L = 0;
            app.part2L = 0;     app.part3L = 0;
            
            disp(value);
            a = value;
            cd_path_ListBoxValueChanged = cd(".\\");
            find_idx=strfind(cd_path_ListBoxValueChanged,'sample');
            app.cd_path = cd_path_ListBoxValueChanged(1:(find_idx-2));
            
            app.cd_path = strrep(app.cd_path,'\','\\');
            mkdir([app.cd_path,'\\Role_48']);
            mkdir([app.cd_path,'\\Role_48\\',a]);
            
            if ~isfolder([app.cd_path,'\\sample_block\\',a,'\\ACTH_10'])
                system(['"D:\\Python32\\python.exe" ',app.cd_path,'\\extract_images\\new.py']);
                
            end
            
            app.equal_ACTH = app.equal_background(imread([app.cd_path,'\\sample_block\\',a,'\\ACTH_0.625.jpg']));
            if ~isfile([app.cd_path,'\\sample_block\\',a,'\\equal_ACTH.txt'])
                writematrix(app.equal_ACTH, [app.cd_path,'\\sample_block\\',a,'\\equal_ACTH.txt']);
            end
            
            app.All_segment_cluster_registration(a);
            
            
            % b = dir('.\\Block_ACTH_0.625\\?.png');
            b = dir([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_0.625\\?.png']);
            for i = 1:size(b,1)
                
                app.All_prepare(i,a);
                % testP=imread(sprintf('.\\Block_ACTH_10\\%d.png',i));
                testP=imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\%d.png'],i));
                testP=testP(:,:,3); 
                
                testP(testP>220)=255; % 亮度较高的部分直接视为255白色背景
                % samplesP=testP(:);
                
                [NP,~]=histcounts(testP,'BinLimits',[0,255],'BinMethod','integers');
                 NP = smoothdata(NP);
                [~,locsP]=findpeaks(NP);
                [~,valleyP]=findpeaks(-NP);
                %samplesP=samples(samplesP<220);
                if isfile(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\ACTH_Manual_L_CNN%d.png'],i))
                    testL=imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\ACTH_Manual_L_CNN%d.png'],i));
                else
                    testL=imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\ACTH_L_CNN%d.png'],i));
                end
                testL=testL(:,:,3);
                testL(testL>220)=255;
                % testL(testL==0)=255;
                
                [NL,~]=histcounts(testL,'BinLimits',[0,255],'BinMethod','integers');
                 NL = smoothdata(NL);
                [~,locsL]=findpeaks(NL);
                [~,valleyL]=findpeaks(-NL);  
                
%                 k = 2;
%                 [musP, sigmaP, weightsP] = GMMfit(samplesP, k);
%                 [musP, index] = sort(musP);
%                 sigmaP = sigmaP(index);
%                 weightsP = weightsP(index);
                
                Ptpit=imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\%d.png'],i));
                Ptpit=Ptpit(:,:,3);
                if isfile(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\Tpit_Manual_L_CNN%d.png'],i))
                    Ltpit=imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\Tpit_Manual_L_CNN%d.png'],i));
                else
                    Ltpit=imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_ACTH_10\\Tpit_L_CNN%d.png'],i));
                end
                Ltpit=Ltpit(:,:,3);
                
                if (isfile([app.cd_path,'\\sample_block\\',a,'\\equal_ACTH.txt']))
                    app.equal_ACTH = load([app.cd_path,'\\sample_block\\',a,'\\equal_ACTH.txt']);
                end
                
                
                if (isfile(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\coincidence_part_%d.png'],i)))
                    app.coincidence_part = imread(sprintf([app.cd_path,'\\sample_block\\',a,'\\Block_Tpit_10\\coincidence_part_%d.png'],i));
                end
                
                [cP(i),P1(i),P2(i),P3(i),cL(i),L1(i),L2(i),L3(i)] = app.statistic(testP,testL); 
                app.countP = app.countP + cP(i);     app.countL = app.countL + cL(i);
                app.part1P = app.part1P + P1(i);     app.part2P = app.part2P + P2(i);
                app.part3P = app.part3P + P3(i);     app.part1L = app.part1L + L1(i);
                app.part2L = app.part2L + L2(i);     app.part3L = app.part3L + L3(i); 
                
                if isfolder(sprintf([app.cd_path,'\\sample_block\\',a,'\\Select_ACTH_%d'],i))
                    rmdir(sprintf([app.cd_path,'\\sample_block\\',a,'\\Select_ACTH_%d'],i),"s");
                end
                if isfolder(sprintf([app.cd_path,'\\sample_block\\',a,'\\Select_Tpit_%d'],i))
                    rmdir(sprintf([app.cd_path,'\\sample_block\\',a,'\\Select_Tpit_%d'],i),"s");
                end
            end
            
            if isfolder([app.cd_path,'\\sample_block\\',a,'\\Block_pad_ACTH_10'])
                rmdir([app.cd_path,'\\sample_block\\',a,'\\Block_pad_ACTH_10'],"s"); % rmdir a.txt s对应函数形式rmdir("a.txt","s");
            end
            if isfolder([app.cd_path,'\\sample_block\\',a,'\\Block_pad_Tpit_10'])
                rmdir([app.cd_path,'\\sample_block\\',a,'\\Block_pad_Tpit_10'],"s"); % rmdir a.txt s对应函数形式rmdir("a.txt","s");
            end
%            
               
            if app.t4 < app.t3
                app.t4 = app.t3;
            end
            % app.t2=musP(1)-half_width1;
            if app.t2 < 0
                app.t2 = 0;
            end
%             app.b=locsP(end)-locsL(end);
            disp('t2_,t3_,t4_');
            disp(app.t2*(app.equal_ACTH/213));
            disp(app.t3*(app.equal_ACTH/213));
            disp(app.t4*(app.equal_ACTH/213));
            
            recalculate(app);
            
%            
            
            table = array2table((app.UITable.Data)','RowNames',{a},'VariableNames',app.UITable.RowName);
            if app.first_line    
                writetable(table,[app.cd_path,'\\ACTH_3x.xlsx'],'WriteRowNames',true);
                app.first_line = 0;    
            else
                app.order = app.order + 1;   
                if app.order < 10
                    writetable(table,[app.cd_path,'\\ACTH_3x.xlsx'],'WriteRowNames',true,'WriteVariableNames',false,'sheet','Sheet1','Range',sprintf('A%d',app.order));
                elseif app.order < 100
                    writetable(table,[app.cd_path,'\\ACTH_3x.xlsx'],'WriteRowNames',true,'WriteVariableNames',false,'sheet','Sheet1','Range',sprintf('A%2d',app.order));
                elseif app.order < 1000
                    writetable(table,[app.cd_path,'\\ACTH_3x.xlsx'],'WriteRowNames',true,'WriteVariableNames',false,'sheet','Sheet1','Range',sprintf('A%3d',app.order));
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.d = uigetdir();
            files = dir(app.d);
            subdirs = {};
            for i = 1:length(files)
                file = files(i);
                if file.isdir && ~strcmp(file.name,'.') && ~strcmp(file.name,'..') 
                    subdirs{length(subdirs)+1}=file.name;
                end
            end
            app.ListBox.Items = subdirs;  
        end

        % Button down function: UIAxes
        function mouseDown(app, event)
            app.moving = ~app.moving;  % 单刀双掷开关,初始值是false不执行
            
            if (app.moving)
                image_location = [app.movable_image.XData(1) app.movable_image.YData(1)];
                app.start_relative_location = image_location-event.IntersectionPoint(1:2);
            end
            
        end

        % Window button motion function: UIFigure
        function mouseMove(app, event)
            if app.CheckBox.Value  % 按下才执行,不按下不执行  
                if (app.moving)
                    app.new_location = app.UIAxes.CurrentPoint(1,1:2) + app.start_relative_location; % CurruntPoint就是鼠标的位置,先x后y
%                     app.width = app.movable_image.XData(2)-app.movable_image.XData(1);
%                     app.height = app.movable_image.YData(2)-app.movable_image.YData(1);
                    
                    app.movable_image.XData = [app.new_location(1) app.new_location(1)+app.diag_length];
                    app.movable_image.YData = [app.new_location(2) app.new_location(2)+app.diag_length];
    %                 drawnow limitrate;
                end
            end
        end

        % Value changed function: angleSlider
        function angleChanged(app, event)
            value = app.angleSlider.Value;
            new_image = imrotate(app.orig_image, value, 'bilinear', 'loose');
            
            begin_pad = floor([app.diag_length-size(new_image, 1), app.diag_length-size(new_image, 2)]/2);
            end_pad = [app.diag_length-size(new_image, 1)-begin_pad(1), app.diag_length-size(new_image, 2)-begin_pad(2)];
            disp_im = padarray(new_image, [begin_pad 0], 0, 'pre');
            disp_im = padarray(disp_im, [end_pad 0], 0, 'post');
            
            app.movable_image.CData = disp_im;
%             drawnow limitrate;
        end

        % Button pushed function: ButtonDone
        function DonePushed(app, event)
            app.Done = 1;% 这里会触发上面的waitfor
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @mouseMove, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @mouseDown, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '病例';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择文件夹';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Percent'};
            app.UITable.RowName = {};
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '手动配准';
            app.angleLabel = uilabel(app.UIFigure);
            app.angleLabel.Text = 'angle';
            app.angleSlider = uislider(app.UIFigure);
            app.angleSlider.Limits = [0 360];
            app.angleSlider.ValueChangedFcn = createCallbackFcn(app, @angleChanged, true);
            app.ButtonDone = uibutton(app.UIFigure, 'push');
            app.ButtonDone.ButtonPushedFcn = createCallbackFcn(app, @DonePushed, true);
            app.ButtonDone.Text = '完成';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ACTHselect_CNN_coincidence_Github

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end